classdef Parameter_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CwSpinner                  matlab.ui.control.Spinner
        CwSpinnerLabel             matlab.ui.control.Label
        TeEditField                matlab.ui.control.NumericEditField
        TeEditFieldLabel           matlab.ui.control.Label
        TriseEditField             matlab.ui.control.NumericEditField
        TriseEditFieldLabel        matlab.ui.control.Label
        TCTEditField               matlab.ui.control.NumericEditField
        TCTEditFieldLabel          matlab.ui.control.Label
        DefaultButton              matlab.ui.control.StateButton
        WelcomeUserPleaseTuneParametersBeforeSimulationStartLabel  matlab.ui.control.Label
        CreatePButton              matlab.ui.control.Button
        Ppl0Spinner                matlab.ui.control.Spinner
        Ppl0SpinnerLabel           matlab.ui.control.Label
        Flow0Spinner               matlab.ui.control.Spinner
        Flow0SpinnerLabel          matlab.ui.control.Label
        Pao0Spinner                matlab.ui.control.Spinner
        Pao0SpinnerLabel           matlab.ui.control.Label
        Palv0Spinner               matlab.ui.control.Spinner
        Palv0SpinnerLabel          matlab.ui.control.Label
        V0Spinner                  matlab.ui.control.Spinner
        V0SpinnerLabel             matlab.ui.control.Label
        InitialValuesLabel         matlab.ui.control.Label
        TdeflateSpinner            matlab.ui.control.Spinner
        TdeflateSpinnerLabel       matlab.ui.control.Label
        sim_lgthSpinner            matlab.ui.control.Spinner
        sim_lgthSpinnerLabel       matlab.ui.control.Label
        dtSpinner                  matlab.ui.control.Spinner
        dtSpinnerLabel             matlab.ui.control.Label
        SimulationParametersLabel  matlab.ui.control.Label
        CLSpinner                  matlab.ui.control.Spinner
        CLSpinnerLabel             matlab.ui.control.Label
        CrsSpinner                 matlab.ui.control.Spinner
        CrsSpinnerLabel            matlab.ui.control.Label
        CalvSpinner                matlab.ui.control.Spinner
        CalvSpinnerLabel           matlab.ui.control.Label
        RSpinner                   matlab.ui.control.Spinner
        RSpinnerLabel              matlab.ui.control.Label
        LungPropertiesLabel        matlab.ui.control.Label
        PmusCycleSpinner           matlab.ui.control.Spinner
        PmusCycleSpinnerLabel      matlab.ui.control.Label
        PmusExpLgthSpinner         matlab.ui.control.Spinner
        PmusExpLgthSpinnerLabel    matlab.ui.control.Label
        PmusSetSpinner             matlab.ui.control.Spinner
        PmusSetSpinnerLabel        matlab.ui.control.Label
        PmusPauseSpinner           matlab.ui.control.Spinner
        PmusPauseSpinnerLabel      matlab.ui.control.Label
        PmusTeSpinner              matlab.ui.control.Spinner
        PmusTeSpinnerLabel         matlab.ui.control.Label
        PmusTiSpinner              matlab.ui.control.Spinner
        PmusTiSpinnerLabel         matlab.ui.control.Label
        PmusSettingsLabel          matlab.ui.control.Label
        PSTriggerSpinner           matlab.ui.control.Spinner
        PSTriggerSpinnerLabel      matlab.ui.control.Label
        TiSpinner                  matlab.ui.control.Spinner
        TiSpinnerLabel             matlab.ui.control.Label
        VentSettingsLabel          matlab.ui.control.Label
        RRSpinner                  matlab.ui.control.Spinner
        RRSpinnerLabel             matlab.ui.control.Label
        PEEPSpinner                matlab.ui.control.Spinner
        PEEPSpinnerLabel           matlab.ui.control.Label
        PSSpinner                  matlab.ui.control.Spinner
        PSSpinnerLabel             matlab.ui.control.Label
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
    end

    
    properties (Access = public)
         P % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Set Spinner Values
            
            app.dtSpinner.Value = 0.002; %
            app.Ppl0Spinner.Value = -3; %
            app.Flow0Spinner.Value = 0; %
            app.Pao0Spinner.Value = 0; %
            app.Palv0Spinner.Value = 5; %
            app.V0Spinner.Value = 3; %
            app.TdeflateSpinner.Value = 0.4; %
            app.sim_lgthSpinner.Value  = 10; %Seconds of breath simulation
            app.CLSpinner.Value = 200; %
            app.CrsSpinner.Value = 40; %
            app.CalvSpinner.Value = 0.033; %
            app.RSpinner.Value = 8; % 
            app.PmusCycleSpinner.Value = 2; %
            app.PmusExpLgthSpinner.Value = 0.6; %
            app.PmusSetSpinner.Value = -12; %
            app.PmusPauseSpinner.Value = 0.2; %
            app.PmusTeSpinner.Value = 2.5; %
            app.PmusTiSpinner.Value = 1.5; %
            app.PSTriggerSpinner.Value = -1.5; %           
            app.TiSpinner.Value = 1.5; %
            app.TriseEditField.Value = 0.2*app.TiSpinner.Value; %%%%%%Dynamic%
            app.RRSpinner.Value = 12; %
            app.TCTEditField.Value = 60/app.RRSpinner.Value;%+(2*app.PmusPauseSpinner.Value); %%%%%%Dynamic
            app.TeEditField.Value = app.TCTEditField.Value-app.TiSpinner.Value; %Only Ti adjustable - Te depends on TI and TCT
            app.PEEPSpinner.Value = 5;
            app.PSSpinner.Value = 20;
            app.CwSpinner.Value = 120; %[ml/cmH2o] Calculated as 4% of standard vital capacity

            %% Store Spinner Values in P
            app.P.resp.dt = app.dtSpinner.Value;
            app.P.resp.Ppl0 = app.Ppl0Spinner.Value;
            app.P.resp.Flow0 = app.Flow0Spinner.Value;
            app.P.resp.Pao0 = app.Pao0Spinner.Value;
            app.P.resp.Palv0 = app.Palv0Spinner.Value;
            app.P.resp.V0 = app.V0Spinner.Value;
            app.P.resp.Tdeflate = app.TdeflateSpinner.Value;
            app.P.resp.sim_lgth = app.sim_lgthSpinner.Value;
            app.P.resp.CL = app.CLSpinner.Value;
            app.P.resp.Crs = app.CrsSpinner.Value;
            app.P.resp.Calv = app.CalvSpinner.Value;
            app.P.resp.R = app.RSpinner.Value; %
            app.P.resp.PmusCycle = app.PmusCycleSpinner.Value;
            app.P.resp.PmusExpLgth = app.PmusExpLgthSpinner.Value;
            app.P.resp.PmusSet = app.PmusSetSpinner.Value;
            app.P.resp.PmusPause = app.PmusPauseSpinner.Value;
            app.P.resp.PmusTe = app.PmusTeSpinner.Value;
            app.P.resp.PmusTi = app.PmusTiSpinner.Value;
            app.P.resp.PSTrigger = app.PSTriggerSpinner.Value;             
            app.P.resp.Te = app.TeEditField.Value;
            app.P.resp.Ti = app.TiSpinner.Value;
            app.P.resp.Trise = app.TriseEditField.Value; %%%%%%Dynamic
            app.P.resp.RR = app.RRSpinner.Value;
            app.P.resp.TCT = app.TCTEditField.Value; %%%%%%Dynamic
            app.P.resp.PEEP = app.PEEPSpinner.Value;
            app.P.resp.PS = app.PSSpinner.Value;
            app.P.resp.Cw = app.CwSpinner.Value;
        end

        % Value changed function: dtSpinner
        function dtSpinnerValueChanged(app, event)
            value = app.dtSpinner.Value;
            app.P.resp.dt = value;
        end

        % Button pushed function: CreatePButton
        function CreatePButtonPushed(app, event)
            %global P;
            disp(num2str(app.P.resp.PEEP))
        end

        % Value changed function: Ppl0Spinner
        function Ppl0SpinnerValueChanged(app, event)
            value = app.Ppl0Spinner.Value;
            app.P.resp.Ppl0 = value;
        end

        % Value changed function: Flow0Spinner
        function Flow0SpinnerValueChanged(app, event)
            value = app.Flow0Spinner.Value;
            app.P.resp.Flow0 = value;
        end

        % Value changed function: Pao0Spinner
        function Pao0SpinnerValueChanged(app, event)
            value = app.Pao0Spinner.Value;
            app.P.resp.Pao = value;
        end

        % Value changed function: Palv0Spinner
        function Palv0SpinnerValueChanged(app, event)
            value = app.Palv0Spinner.Value;
            app.P.resp.Palv = value;
        end

        % Value changed function: V0Spinner
        function V0SpinnerValueChanged(app, event)
            value = app.V0Spinner.Value;
            app.P.resp.V0 = value;
        end

        % Value changed function: TdeflateSpinner
        function TdeflateSpinnerValueChanged(app, event)
            value = app.TdeflateSpinner.Value;
            app.P.resp.Tdeflate = value;
        end

        % Value changed function: sim_lgthSpinner
        function sim_lgthSpinnerValueChanged(app, event)
            value = app.sim_lgthSpinner.Value;
            app.P.resp.sim_lgth = value;
        end

        % Value changed function: CLSpinner
        function CLSpinnerValueChanged(app, event)
            value = app.CLSpinner.Value;
            app.P.resp.CL = value;
        end

        % Value changed function: CrsSpinner
        function CrsSpinnerValueChanged(app, event)
            value = app.CrsSpinner.Value;
            app.P.resp.Crs = value;
        end

        % Value changed function: CalvSpinner
        function CalvSpinnerValueChanged(app, event)
            value = app.CalvSpinner.Value;
            app.P.resp.Calv = value;
        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            value = app.RSpinner.Value;
            app.P.resp.R = value;
        end

        % Value changed function: PmusCycleSpinner
        function PmusCycleSpinnerValueChanged(app, event)
            value = app.PmusCycleSpinner.Value;
            app.P.resp.PmusCycle = value;
        end

        % Value changed function: PmusExpLgthSpinner
        function PmusExpLgthSpinnerValueChanged(app, event)
            value = app.PmusExpLgthSpinner.Value;
            app.P.resp.PmusExpLgth = value;
        end

        % Value changed function: PmusSetSpinner
        function PmusSetSpinnerValueChanged(app, event)
            value = app.PmusSetSpinner.Value;
            app.P.resp.PmusSet = value;
        end

        % Value changed function: PmusPauseSpinner
        function PmusPauseSpinnerValueChanged(app, event)
            value = app.PmusPauseSpinner.Value;
            app.P.resp.PmusPause = value;
        end

        % Value changed function: PmusTeSpinner
        function PmusTeSpinnerValueChanged(app, event)
            value = app.PmusTeSpinner.Value;
            app.P.resp.PmusTe = value;
        end

        % Value changed function: PmusTiSpinner
        function PmusTiSpinnerValueChanged(app, event)
            value = app.PmusTiSpinner.Value;
            app.P.resp.PmusTi = value;
        end

        % Value changed function: PSTriggerSpinner
        function PSTriggerSpinnerValueChanged(app, event)
            value = app.PSTriggerSpinner.Value;
            app.P.resp.PSTrigger = value;
        end

        % Callback function
        function TriseSpinnerValueChanged(app, event)
            value = app.TriseSpinner.Value; %Percentage of Ti taken to rise to PS
            app.P.resp.Trise = value*app.TiSpinner.Value;
        end

        % Callback function
        function TeSpinnerValueChanged(app, event)
            value = app.TeSpinner.Value;
            app.P.resp.Te = value;
        end

        % Value changed function: TiSpinner
        function TiSpinnerValueChanged(app, event)
            value = app.TiSpinner.Value;
            app.P.resp.Ti = value;
            app.TriseEditField.Value = value*0.2; %Rise time is 20% if Ti time
            app.P.resp.Trise = app.TriseEditField.Value;
            app.TeEditField.Value = app.TCTEditField.Value-value; %Calculates Te as as function of TCT and Ti
            app.P.resp.Te = app.TeEditField.Value;
        end

        % Callback function
        function TCTSpinnerValueChanged(app, event)
            value = 60/app.RRSpinner.Value;%+(2*app.PmusPauseSpinner.Value);
            app.P.resp.TCT = value;
        end

        % Value changed function: RRSpinner
        function RRSpinnerValueChanged(app, event)
            value = app.RRSpinner.Value;
            app.P.resp.RR = value;
            app.TCTEditField.Value = 60/value;%+(2*app.PmusPauseSpinner.Value);
            app.P.resp.TCT = app.TCTEditField.Value;

            app.TeEditField.Value = app.TCTEditField.Value-app.TiSpinner.Value;
            app.P.resp.Te = app.TeEditField.Value;
        end

        % Value changed function: PEEPSpinner
        function PEEPSpinnerValueChanged(app, event)
            value = app.PEEPSpinner.Value;
            app.P.resp.PEEP = value;
        end

        % Value changed function: PSSpinner
        function PSSpinnerValueChanged(app, event)
            value = app.PSSpinner.Value;
            app.P.resp.PS = value;
        end

        % Callback function: DefaultButton, PEEPSpinner, dtSpinner
        function DefaultButtonValueChanged(app, event)
            %% Set Spinner Values
            
            app.dtSpinner.Value = 0.002; %
            app.Ppl0Spinner.Value = -3; %
            app.Flow0Spinner.Value = 0; %
            app.Pao0Spinner.Value = 0; %
            app.Palv0Spinner.Value = 5; %
            app.V0Spinner.Value = 3; %
            app.TdeflateSpinner.Value = 0.4; %
            app.sim_lgthSpinner.Value  = 10; %Seconds of breath simulation
            app.CLSpinner.Value = 200; %
            app.CrsSpinner.Value = 40; %
            app.CalvSpinner.Value = 0.033; %
            app.RSpinner.Value = 2; % 
            app.PmusCycleSpinner.Value = 2; %
            app.PmusExpLgthSpinner.Value = 0.6; %
            app.PmusSetSpinner.Value = -12; %
            app.PmusPauseSpinner.Value = 0.2; %
            app.PmusTeSpinner.Value = 2.5; %
            app.PmusTiSpinner.Value = 1.5; %
            app.PSTriggerSpinner.Value = -1.5; %           
            app.TiSpinner.Value = 1.5; %
            app.TriseEditField.Value = 0.2*app.TiSpinner.Value; %%%%%%Dynamic%
            app.RRSpinner.Value = 12; %
            app.TCTEditField.Value = (60/app.RRSpinner.Value);%+(2*app.PmusPauseSpinner.Value); %%%%%%Dynamic
            app.TeEditField.Value = app.TCTEditField.Value-app.TiSpinner.Value; %Only Ti adjustable - Te depends on TI and TCT
            app.PEEPSpinner.Value = 5;
            app.PSSpinner.Value = 20;
            app.CwSpinner.Value = 120; %[ml/cmH2o] Calculated as 4% of standard vital capacity

            %% Store Spinner Values in P
            app.P.resp.dt = app.dtSpinner.Value;
            app.P.resp.Ppl0 = app.Ppl0Spinner.Value;
            app.P.resp.Flow0 = app.Flow0Spinner.Value;
            app.P.resp.Pao0 = app.Pao0Spinner.Value;
            app.P.resp.Palv0 = app.Palv0Spinner.Value;
            app.P.resp.V0 = app.V0Spinner.Value;
            app.P.resp.Tdeflate = app.TdeflateSpinner.Value;
            app.P.resp.sim_lgth = app.sim_lgthSpinner.Value;
            app.P.resp.CL = app.CLSpinner.Value;
            app.P.resp.Crs = app.CrsSpinner.Value;
            app.P.resp.Calv = app.CalvSpinner.Value;
            app.P.resp.R = app.RSpinner.Value; %
            app.P.resp.PmusCycle = app.PmusCycleSpinner.Value;
            app.P.resp.PmusExpLgth = app.PmusExpLgthSpinner.Value;
            app.P.resp.PmusSet = app.PmusSetSpinner.Value;
            app.P.resp.PmusPause = app.PmusPauseSpinner.Value;
            app.P.resp.PmusTe = app.PmusTeSpinner.Value;
            app.P.resp.PmusTi = app.PmusTiSpinner.Value;
            app.P.resp.PSTrigger = app.PSTriggerSpinner.Value;             
            app.P.resp.Te = app.TeEditField.Value;
            app.P.resp.Ti = app.TiSpinner.Value;
            app.P.resp.Trise = app.TriseEditField.Value; %%%%%%Dynamic
            app.P.resp.RR = app.RRSpinner.Value;
            app.P.resp.TCT = app.TCTEditField.Value; %%%%%%Dynamic
            app.P.resp.PEEP = app.PEEPSpinner.Value;
            app.P.resp.PS = app.PSSpinner.Value;
            app.P.resp.Cw = app.CwSpinner.Value;
            
        end

        % Value changed function: TCTEditField
        function TCTEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: TeEditField
        function TeEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: CwSpinner
        function CwSpinnerValueChanged(app, event)
            value = app.CwSpinner.Value;
            app.P.resp.Cw = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PSSpinnerLabel = uilabel(app.UIFigure);
            app.PSSpinnerLabel.Text = 'PS';
            app.PSSpinner = uispinner(app.UIFigure);
            app.PSSpinner.ValueChangedFcn = createCallbackFcn(app, @PSSpinnerValueChanged, true);
            app.PEEPSpinnerLabel = uilabel(app.UIFigure);
            app.PEEPSpinnerLabel.Text = 'PEEP';
            app.PEEPSpinner = uispinner(app.UIFigure);
            app.PEEPSpinner.ValueChangingFcn = createCallbackFcn(app, @DefaultButtonValueChanged, true);
            app.PEEPSpinner.ValueChangedFcn = createCallbackFcn(app, @PEEPSpinnerValueChanged, true);
            app.RRSpinnerLabel = uilabel(app.UIFigure);
            app.RRSpinnerLabel.Text = 'RR';
            app.RRSpinner = uispinner(app.UIFigure);
            app.RRSpinner.ValueChangedFcn = createCallbackFcn(app, @RRSpinnerValueChanged, true);
            app.VentSettingsLabel = uilabel(app.UIFigure);
            app.VentSettingsLabel.Text = 'Vent Settings';
            app.TiSpinnerLabel = uilabel(app.UIFigure);
            app.TiSpinnerLabel.Text = 'Ti';
            app.TiSpinner = uispinner(app.UIFigure);
            app.TiSpinner.ValueChangedFcn = createCallbackFcn(app, @TiSpinnerValueChanged, true);
            app.PSTriggerSpinnerLabel = uilabel(app.UIFigure);
            app.PSTriggerSpinnerLabel.Text = 'PSTrigger';
            app.PSTriggerSpinner = uispinner(app.UIFigure);
            app.PSTriggerSpinner.ValueChangedFcn = createCallbackFcn(app, @PSTriggerSpinnerValueChanged, true);
            app.PmusSettingsLabel = uilabel(app.UIFigure);
            app.PmusSettingsLabel.Text = 'Pmus Settings';
            app.PmusTiSpinnerLabel = uilabel(app.UIFigure);
            app.PmusTiSpinnerLabel.Text = 'PmusTi';
            app.PmusTiSpinner = uispinner(app.UIFigure);
            app.PmusTiSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusTiSpinnerValueChanged, true);
            app.PmusTeSpinnerLabel = uilabel(app.UIFigure);
            app.PmusTeSpinnerLabel.Text = 'PmusTe';
            app.PmusTeSpinner = uispinner(app.UIFigure);
            app.PmusTeSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusTeSpinnerValueChanged, true);
            app.PmusPauseSpinnerLabel = uilabel(app.UIFigure);
            app.PmusPauseSpinnerLabel.Text = 'PmusPause';
            app.PmusPauseSpinner = uispinner(app.UIFigure);
            app.PmusPauseSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusPauseSpinnerValueChanged, true);
            app.PmusSetSpinnerLabel = uilabel(app.UIFigure);
            app.PmusSetSpinnerLabel.Text = 'PmusSet';
            app.PmusSetSpinner = uispinner(app.UIFigure);
            app.PmusSetSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusSetSpinnerValueChanged, true);
            app.PmusExpLgthSpinnerLabel = uilabel(app.UIFigure);
            app.PmusExpLgthSpinnerLabel.Text = 'PmusExpLgth';
            app.PmusExpLgthSpinner = uispinner(app.UIFigure);
            app.PmusExpLgthSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusExpLgthSpinnerValueChanged, true);
            app.PmusCycleSpinnerLabel = uilabel(app.UIFigure);
            app.PmusCycleSpinnerLabel.Text = 'PmusCycle';
            app.PmusCycleSpinner = uispinner(app.UIFigure);
            app.PmusCycleSpinner.ValueChangedFcn = createCallbackFcn(app, @PmusCycleSpinnerValueChanged, true);
            app.LungPropertiesLabel = uilabel(app.UIFigure);
            app.LungPropertiesLabel.Text = 'Lung Properties';
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.CalvSpinnerLabel = uilabel(app.UIFigure);
            app.CalvSpinnerLabel.Text = 'Calv';
            app.CalvSpinner = uispinner(app.UIFigure);
            app.CalvSpinner.ValueChangedFcn = createCallbackFcn(app, @CalvSpinnerValueChanged, true);
            app.CrsSpinnerLabel = uilabel(app.UIFigure);
            app.CrsSpinnerLabel.Text = 'Crs';
            app.CrsSpinner = uispinner(app.UIFigure);
            app.CrsSpinner.ValueChangedFcn = createCallbackFcn(app, @CrsSpinnerValueChanged, true);
            app.CLSpinnerLabel = uilabel(app.UIFigure);
            app.CLSpinnerLabel.Text = 'CL';
            app.CLSpinner = uispinner(app.UIFigure);
            app.CLSpinner.ValueChangedFcn = createCallbackFcn(app, @CLSpinnerValueChanged, true);
            app.SimulationParametersLabel = uilabel(app.UIFigure);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.dtSpinnerLabel = uilabel(app.UIFigure);
            app.dtSpinnerLabel.Text = 'dt';
            app.dtSpinner = uispinner(app.UIFigure);
            app.dtSpinner.ValueChangingFcn = createCallbackFcn(app, @DefaultButtonValueChanged, true);
            app.dtSpinner.ValueChangedFcn = createCallbackFcn(app, @dtSpinnerValueChanged, true);
            app.sim_lgthSpinnerLabel = uilabel(app.UIFigure);
            app.sim_lgthSpinnerLabel.Text = 'sim_lgth';
            app.sim_lgthSpinner = uispinner(app.UIFigure);
            app.sim_lgthSpinner.ValueChangedFcn = createCallbackFcn(app, @sim_lgthSpinnerValueChanged, true);
            app.TdeflateSpinnerLabel = uilabel(app.UIFigure);
            app.TdeflateSpinnerLabel.Text = 'Tdeflate';
            app.TdeflateSpinner = uispinner(app.UIFigure);
            app.TdeflateSpinner.ValueChangedFcn = createCallbackFcn(app, @TdeflateSpinnerValueChanged, true);
            app.InitialValuesLabel = uilabel(app.UIFigure);
            app.InitialValuesLabel.Text = 'Initial Values';
            app.V0SpinnerLabel = uilabel(app.UIFigure);
            app.V0SpinnerLabel.Text = 'V0';
            app.V0Spinner = uispinner(app.UIFigure);
            app.V0Spinner.ValueChangedFcn = createCallbackFcn(app, @V0SpinnerValueChanged, true);
            app.Palv0SpinnerLabel = uilabel(app.UIFigure);
            app.Palv0SpinnerLabel.Text = 'Palv0';
            app.Palv0Spinner = uispinner(app.UIFigure);
            app.Palv0Spinner.ValueChangedFcn = createCallbackFcn(app, @Palv0SpinnerValueChanged, true);
            app.Pao0SpinnerLabel = uilabel(app.UIFigure);
            app.Pao0SpinnerLabel.Text = 'Pao0';
            app.Pao0Spinner = uispinner(app.UIFigure);
            app.Pao0Spinner.ValueChangedFcn = createCallbackFcn(app, @Pao0SpinnerValueChanged, true);
            app.Flow0SpinnerLabel = uilabel(app.UIFigure);
            app.Flow0SpinnerLabel.Text = 'Flow0';
            app.Flow0Spinner = uispinner(app.UIFigure);
            app.Flow0Spinner.ValueChangedFcn = createCallbackFcn(app, @Flow0SpinnerValueChanged, true);
            app.Ppl0SpinnerLabel = uilabel(app.UIFigure);
            app.Ppl0SpinnerLabel.Text = 'Ppl0';
            app.Ppl0Spinner = uispinner(app.UIFigure);
            app.Ppl0Spinner.ValueChangedFcn = createCallbackFcn(app, @Ppl0SpinnerValueChanged, true);
            app.CreatePButton = uibutton(app.UIFigure, 'push');
            app.CreatePButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePButtonPushed, true);
            app.CreatePButton.Text = 'Create P';
            app.WelcomeUserPleaseTuneParametersBeforeSimulationStartLabel = uilabel(app.UIFigure);
            app.WelcomeUserPleaseTuneParametersBeforeSimulationStartLabel.Text = {'Welcome <User> '; 'Please Tune Parameters Before Simulation Start '};
            app.DefaultButton = uibutton(app.UIFigure, 'state');
            app.DefaultButton.ValueChangedFcn = createCallbackFcn(app, @DefaultButtonValueChanged, true);
            app.DefaultButton.Text = 'Default';
            app.TCTEditFieldLabel = uilabel(app.UIFigure);
            app.TCTEditFieldLabel.Text = 'TCT';
            app.TCTEditField = uieditfield(app.UIFigure, 'numeric');
            app.TCTEditField.ValueChangedFcn = createCallbackFcn(app, @TCTEditFieldValueChanged, true);
            app.TriseEditFieldLabel = uilabel(app.UIFigure);
            app.TriseEditFieldLabel.Text = 'Trise';
            app.TriseEditField = uieditfield(app.UIFigure, 'numeric');
            app.TeEditFieldLabel = uilabel(app.UIFigure);
            app.TeEditFieldLabel.Text = 'Te';
            app.TeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TeEditField.ValueChangedFcn = createCallbackFcn(app, @TeEditFieldValueChanged, true);
            app.CwSpinnerLabel = uilabel(app.UIFigure);
            app.CwSpinnerLabel.Text = 'Cw';
            app.CwSpinner = uispinner(app.UIFigure);
            app.CwSpinner.ValueChangedFcn = createCallbackFcn(app, @CwSpinnerValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.TCTEditField.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parameter_Settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end